classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        InfoButton_2                    matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        OutlierInfo                     matlab.ui.control.Button
        OutlierRemovalLabel             matlab.ui.control.Label
        OrderSlider                     matlab.ui.control.Slider
        OrderLabel                      matlab.ui.control.Label
        AutomaticfilterparametersLabel  matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        FrequencyAnalysisLabel          matlab.ui.control.Label
        ProcessingTypeLabel             matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        MovingRMSButton                 matlab.ui.control.RadioButton
        BandpassfilterrectificationButton  matlab.ui.control.RadioButton
        Tree2                           matlab.ui.container.Tree
        MTrapDescendensLNode_2          matlab.ui.container.TreeNode
        MTrapDescendensRNode_2          matlab.ui.container.TreeNode
        MDeltodeusAnteriorLNode_2       matlab.ui.container.TreeNode
        MDeltodeusAnteriorRNode_2       matlab.ui.container.TreeNode
        MExtCarpiRadialisdomNode_2      matlab.ui.container.TreeNode
        lampCH5                         matlab.ui.control.Lamp
        lampCH4                         matlab.ui.control.Lamp
        lampCH3                         matlab.ui.control.Lamp
        lampCH2                         matlab.ui.control.Lamp
        lampCH1                         matlab.ui.control.Lamp
        Image_2                         matlab.ui.control.Image
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        ExportdataButton                matlab.ui.control.Button
        LoaddataButton                  matlab.ui.control.Button
        DataManagementLabel             matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        MainPlot                        matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        HzLabel_2                       matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        LowEditField                    matlab.ui.control.NumericEditField
        LowEditFieldLabel               matlab.ui.control.Label
        SgOrder                         matlab.ui.control.Slider
        Slider_2Label_5                 matlab.ui.control.Label
        Slider_2Label_4                 matlab.ui.control.Label
        SgLength                        matlab.ui.control.Slider
        RmsOverlap                      matlab.ui.control.Slider
        Slider_2Label_3                 matlab.ui.control.Label
        Slider_2Label_2                 matlab.ui.control.Label
        RmsWindow                       matlab.ui.control.Slider
        RMSparametersLabel              matlab.ui.control.Label
        InfoButton                      matlab.ui.control.Button
        ApplySavitzkyGolayCheckBox      matlab.ui.control.CheckBox
        OrderEditField                  matlab.ui.control.NumericEditField
        OrderEditFieldLabel             matlab.ui.control.Label
        HighEditField                   matlab.ui.control.NumericEditField
        HighEditFieldLabel              matlab.ui.control.Label
        FilterDropDown                  matlab.ui.control.DropDown
        BandPassparametersLabel         matlab.ui.control.Label
        FilterPlotPhase                 matlab.ui.control.UIAxes
        FilterPlot                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        baseFileExists = 0;
        RAWch

        dataCh1
        dataCh2
        dataCh3
        dataCh4
        dataCh5

        mvcCh1
        mvcCh2
        mvcCh3
        mvcCh4
        mvcCh5

        RAWmvcCh1
        RAWmvcCh2
        RAWmvcCh3
        RAWmvcCh4
        RAWmvcCh5

        mvcREAD = [0, 0, 0, 0, 0]

        track = false;
        filter = 1;
        filter_order = 1
        filter_low = 0.2;
        filter_high = 0.5;
        outlier = 1;
        outlier_order = 1;

        rms_window = 4;
        rms_overlap = 0;

        sg_enabled = false;
        sg_order = 3;
        sg_length = 7;

        fftData
        sRate = 500;


    end
    
    methods (Access = private)
       
        function reCalculateData(app)
            disp("RECALCULATING")
            if (app.mvcREAD(1) ~= 0)
                output = dataProcessing(app.RAWmvcCh1, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high, app.rms_window, app.rms_overlap, app.sg_enabled, app.sg_order, app.sg_length);
                app.mvcCh1 = output(1);
            end
            if (app.mvcREAD(2) ~= 0)
                output = dataProcessing(app.RAWmvcCh2, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high, app.rms_window, app.rms_overlap, app.sg_enabled, app.sg_order, app.sg_length);
                app.mvcCh2 = output(2);
            end
            if (app.mvcREAD(3) ~= 0)
            output = dataProcessing(app.RAWmvcCh3, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high, app.rms_window, app.rms_overlap, app.sg_enabled, app.sg_order, app.sg_length);
            app.mvcCh3 = output(3);
            end
            if (app.mvcREAD(4) ~= 0)
            output = dataProcessing(app.RAWmvcCh4, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high, app.rms_window, app.rms_overlap, app.sg_enabled, app.sg_order, app.sg_length);
            app.mvcCh4 = output(4);
            end
            if (app.mvcREAD(5) ~= 0)
            output = dataProcessing(app.RAWmvcCh5, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high, app.rms_window, app.rms_overlap, app.sg_enabled, app.sg_order, app.sg_length);
            app.mvcCh5 = output(5);
            end
            
            if (app.baseFileExists ~= 0)
                disp("Data recalculated")
                output = dataProcessing(app.RAWch, app.outlier_order, app.outlier, app.track, false, app.filter, app.filter_order, app.filter_low, app.filter_high, app.rms_window, app.rms_overlap, app.sg_enabled, app.sg_order, app.sg_length);
                disp(app.Tree2)
                if (isempty(app.Tree2.SelectedNodes))
                    return
                end
                if (app.Tree2.SelectedNodes.Text == "M. Trap. Descendens (L)")
                    if (app.mvcREAD(1) ~= 0)
                        app.fftData = ProcessMVC(output(:,1), app.mvcCh1);
                        N = size(app.fftData, 1);
                        df = 1000/N; %frequency resolution
                        sampleIndex = 0:N/2; %ordered index for FFT plot
                        f = sampleIndex*df; %x-axis index converted to ordered frequencies
                        plot(app.MainPlot, f,abs(app.fftData(N/2:N, 1)))
                        disp(" Not Empty")
                    end
    
            
                elseif (app.Tree2.SelectedNodes.Text == "M. Trap. Descendens (R)")
                    if (app.mvcREAD(2) ~= 0)
                        app.fftData = ProcessMVC(output(:,2), app.mvcCh2);
                        N = size(app.fftData, 1);
                        df = 1000/N; %frequency resolution
                        sampleIndex = 0:N/2; %ordered index for FFT plot
                        f = sampleIndex*df; %x-axis index converted to ordered frequencies
                        plot(app.MainPlot, f,abs(app.fftData(N/2:N, 1)))
                        disp(" Not Empty")
                    end
        
                elseif (app.Tree2.SelectedNodes.Text == "M. Deltoïdeus Anterior (L)")
                    if (app.mvcREAD(3) ~= 0)
                        app.fftData = ProcessMVC(output(:,3), app.mvcCh3);
                        N = size(app.fftData, 1);
                        df = 1000/N; %frequency resolution
                        sampleIndex = 0:N/2; %ordered index for FFT plot
                        f = sampleIndex*df; %x-axis index converted to ordered frequencies
                        plot(app.MainPlot, f,abs(app.fftData(N/2:N, 1)))
                        disp(" Not Empty")
                    end
        
                elseif (app.Tree2.SelectedNodes.Text == "M. Deltoïdeus Anterior (R)")
                    if (app.mvcREAD(4) ~= 0)
                        app.fftData = ProcessMVC(output(:,4), app.mvcCh4);
                        N = size(app.fftData, 1);
                        df = 1000/N; %frequency resolution
                        sampleIndex = 0:N/2; %ordered index for FFT plot
                        f = sampleIndex*df; %x-axis index converted to ordered frequencies
                        plot(app.MainPlot, f,abs(app.fftData(N/2:N, 1)))
                        disp(" Not Empty")
                    end
        
                elseif (app.Tree2.SelectedNodes.Text == "M. Ext. Carpi Radialis (dom.)")
                    if (app.mvcREAD(5) ~= 0)
                        app.fftData = ProcessMVC(output(:,5), app.mvcCh5);
                        N = size(app.fftData, 1);
                        df = 1000/N; %frequency resolution
                        sampleIndex = 0:N/2; %ordered index for FFT plot
                        f = sampleIndex*df; %x-axis index converted to ordered frequencies
                        plot(app.MainPlot, f,abs(app.fftData(N/2:N, 1)))

                        disp(" Not Empty")
                    end

                end
            else
                uiconfirm(app.UIFigure,'No base channel has been added! Pleas push the "Load data" button.','Error!',...
                                    'Icon','warning');
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FilterDropDownValueChanged2();
        end

        % Callback function
        function FilterDropDownValueChanged(app, event)
            value = app.FilterDropDown.Value;
            
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            if isequal(file,0)
               disp('User selected Cancel');
            else
                disp(fullfile(path,file))
                [app.RAWch, ~, ~, app.sRate] = FileParser(fullfile(path,file));

                output = dataProcessing(app.RAWch, app.outlier_order, app.outlier, app.track, false, app.filter, app.filter_order, app.filter_low, app.filter_high, app.rms_window, app.rms_overlap, app.sg_enabled, app.sg_order, app.sg_length);
                app.dataCh1 = ProcessMVC(output(1),app.mvcCh1);
                app.dataCh2 = ProcessMVC(output(2),app.mvcCh2);
                app.dataCh3 = ProcessMVC(output(3),app.mvcCh3);
                app.dataCh4 = ProcessMVC(output(4),app.mvcCh4);
                app.dataCh5 = ProcessMVC(output(5),app.mvcCh5);

                app.baseFileExists = 1;

                uiconfirm(app.UIFigure,'File has been loaded succesfully! Please select a channel.','File loaded',...
                                    'Icon','info');
                app.reCalculateData();
            end
        end

        % Button pushed function: ExportdataButton
        function ExportdataButtonPushed(app, event)
            if (isempty(app.Tree2.SelectedNodes))
                return
            end

            selpath = uigetdir(matlabroot,'Choose folder to save the analysis');
            disp(strcat(selpath, "/M_Ext_Carpi_Radialis_dom-export.csv"))

            if (app.Tree2.SelectedNodes.Text == "M. Trap. Descendens (L)")
                Output(strcat(selpath, "/M_Trap_Descendens_L-export.csv") , app.fftData, app.outlier_order, app.outlier, app.track, false, app.filter, app.filter_order, app.filter_low*(app.sRate/2), app.filter_high*(app.sRate/2), app.rms_window, app.rms_overlap, app.sg_enabled, app.sg_order, app.sg_length);
            
            elseif (app.Tree2.SelectedNodes.Text == "M. Trap. Descendens (R)")
                Output(strcat(selpath, "/M_Trap_Descendens_R-export.csv") , app.fftData, app.outlier_order, app.outlier, app.track, false, app.filter, app.filter_order, app.filter_low*(app.sRate/2), app.filter_high*(app.sRate/2), app.rms_window, app.rms_overlap, app.sg_enabled, app.sg_order, app.sg_length);
                    
            elseif (app.Tree2.SelectedNodes.Text == "M. Deltoïdeus Anterior (L)")
                Output(strcat(selpath, "/M_Deltoïdeus_Anterior_L-export.csv") , app.fftData, app.outlier_order, app.outlier, app.track, false, app.filter, app.filter_order, app.filter_low*(app.sRate/2), app.filter_high*(app.sRate/2), app.rms_window, app.rms_overlap, app.sg_enabled, app.sg_order, app.sg_length);
                    
            elseif (app.Tree2.SelectedNodes.Text == "M. Deltoïdeus Anterior (R)")
                Output(strcat(selpath, "/M_Deltoïdeus_Anterior_R-export.csv") , app.fftData, app.outlier_order, app.outlier, app.track, false, app.filter, app.filter_order, app.filter_low*(app.sRate/2), app.filter_high*(app.sRate/2), app.rms_window, app.rms_overlap, app.sg_enabled, app.sg_order, app.sg_length);
                   
            elseif (app.Tree2.SelectedNodes.Text == "M. Ext. Carpi Radialis (dom.)")
                Output(strcat(selpath, "/M_Ext_Carpi_Radialis_dom-export.csv") , app.fftData, app.outlier_order, app.outlier, app.track, false, app.filter, app.filter_order, app.filter_low*(app.sRate/2), app.filter_high*(app.sRate/2), app.rms_window, app.rms_overlap, app.sg_enabled, app.sg_order, app.sg_length);
            end

            uiconfirm(app.UIFigure,'File has been exported succesfully! Have fun in Excel...','File exported',...
                                    'Icon','info');
        end

        % Callback function
        function LoadMVCButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
               app.lampCH1.Color = [0 1 0];
            end   
        end

        % Callback function
        function CleardataButtonPushed(app, event)

            selection = uiconfirm(app.UIFigure,'Clear data?','Confirm Clearing',...
                                    'Icon','warning');
            % Handle response
            switch selection
                case 'OK'
                    disp('Clearing...')
                    app.lampCH1.Color = [1 0 0];
                    app.Lamp.Color = [1 0 0];
                case 'Cancel'
                    disp('...')
            end
        end

        % Callback function
        function TreeCheckedNodesChanged(app, event)

        end

        % Value changed function: RmsWindow
        function RmsWindowValueChanged(app, event)
            value = app.RmsWindow.Value;
            app.rms_window = round(value);
            app.RmsWindow.Value = app.rms_window;
            app.RmsOverlap.Limits = [0, app.rms_window-1];
            app.reCalculateData();
    
            
        end

        % Selection changed function: Tree2
        function Tree2SelectionChanged(app, event)
            selectedNodes = app.Tree2.SelectedNodes;
            if (selectedNodes.Text == "M. Trap. Descendens (L)")
                if (app.mvcCh1 ~= 0)
                    app.reCalculateData();
                else
                    selection = uiconfirm(app.UIFigure,'Please select MVC for M. Trap. Descendens (L)!','MVC M. Trap. Descendens (L)',...
                                    'Icon','info');
                    switch selection
                        case 'OK'
                            [file,path] = uigetfile('*.txt');
                            if isequal(file,0)
                               disp('User selected Cancel');
                            else
                                [app.RAWmvcCh1, ~, ~] = FileParser(fullfile(path,file));
                                output = dataProcessing(app.RAWmvcCh1, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high, app.rms_window, app.rms_overlap, app.sg_enabled, app.sg_order, app.sg_length);
                                app.mvcCh1 = output(1);
                                app.lampCH1.Color = [0 1 0];
                                app.mvcREAD(1) = 1;
                                app.reCalculateData();

                            end
                    end
                end

        
            elseif (selectedNodes.Text == "M. Trap. Descendens (R)")
                if (app.mvcCh2 ~= 0)
                    app.reCalculateData();
                else
                    disp("Empty")
                    selection = uiconfirm(app.UIFigure,'Please select MVC for M. Trap. Descendens (R)!','MVC M. Trap. Descendens (R)',...
                                    'Icon','info');
                    switch selection
                        case 'OK'
                            [file,path] = uigetfile('*.txt');
                            if isequal(file,0)
                               disp('User selected Cancel');
                            else
                                [app.RAWmvcCh2, ~, ~] = FileParser(fullfile(path,file));
                                output = dataProcessing(app.RAWmvcCh2, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high, app.rms_window, app.rms_overlap, app.sg_enabled, app.sg_order, app.sg_length);
                                app.mvcCh2 = output(2);
                                app.lampCH2.Color = [0 1 0];
                                app.mvcREAD(2) = 1;
                                app.reCalculateData();
                            end
                    end
                end
    
            elseif (selectedNodes.Text == "M. Deltoïdeus Anterior (L)")
                if (app.mvcCh3 ~= 0)
                    app.reCalculateData();
                else
                    disp("Empty")
                    selection = uiconfirm(app.UIFigure,'Please select MVC for M. Deltoïdeus Anterior (L)!','MVC M. Deltoïdeus Anterior (L)',...
                                    'Icon','info');
                    switch selection
                        case 'OK'
                            [file,path] = uigetfile('*.txt');
                            if isequal(file,0)
                               disp('User selected Cancel');
                            else
                                [app.RAWmvcCh3, ~, ~] = FileParser(fullfile(path,file));
                                output = dataProcessing(app.RAWmvcCh3, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high, app.rms_window, app.rms_overlap, app.sg_enabled, app.sg_order, app.sg_length);
                                app.mvcCh3 = output(3);
                                app.lampCH3.Color = [0 1 0];
                                app.mvcREAD(3) = 1;
                                app.reCalculateData();
                            end
                    end
                end
    
            elseif (selectedNodes.Text == "M. Deltoïdeus Anterior (R)")
                if (app.mvcCh4 ~= 0)
                    app.reCalculateData();
                else
                    disp("Empty")
                    selection = uiconfirm(app.UIFigure,'Please select MVC for M. Deltoïdeus Anterior (R)!','MVC M. Deltoïdeus Anterior (R)',...
                                    'Icon','info');
                    switch selection
                        case 'OK'
                            [file,path] = uigetfile('*.txt');
                            if isequal(file,0)
                               disp('User selected Cancel');
                            else
                                [app.RAWmvcCh4, ~, ~] = FileParser(fullfile(path,file));
                                output = dataProcessing(app.RAWmvcCh4, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high, app.rms_window, app.rms_overlap, app.sg_enabled, app.sg_order, app.sg_length);
                                app.mvcCh4 = output(4);
                                app.lampCH4.Color = [0 1 0];
                                app.mvcREAD(4) = 1;
                                app.reCalculateData();
                            end
                    end
                end
    
            elseif (selectedNodes.Text == "M. Ext. Carpi Radialis (dom.)")
                if (app.mvcCh1 ~= 0)
                    app.reCalculateData();
                else
                    disp("Empty")
                    selection = uiconfirm(app.UIFigure,'Please select MVC for M. Ext. Carpi Radialis (dom.)!','MVC M. Ext. Carpi Radialis (dom.)',...
                                    'Icon','info');
                    switch selection
                        case 'OK'
                            [file,path] = uigetfile('*.txt');
                            if isequal(file,0)
                               disp('User selected Cancel');
                            else
                                [app.RAWmvcCh5, ~, ~] = FileParser(fullfile(path,file));
                                output = dataProcessing(app.RAWmvcCh5, app.outlier_order, app.outlier, app.track, true, app.filter, app.filter_order, app.filter_low, app.filter_high, app.rms_window, app.rms_overlap, app.sg_enabled, app.sg_order, app.sg_length);
                                app.mvcCh5 = output(5);
                                app.lampCH5.Color = [0 1 0];
                                app.mvcREAD(5) = 1;
                                app.reCalculateData();
                            end
                    end
                end
            else
                disp("idk")
            end
        end

        % Value changed function: OrderSlider
        function OrderSliderValueChanged(app, event)
            value = app.OrderSlider.Value;
            app.OrderSlider.Value = round(value);
            app.outlier_order = round(value);
            app.reCalculateData();
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if (selectedButton.Text == "Moving RMS")
                app.track = true;
                app.RmsOverlap.Enable = true;
                app.RmsWindow.Enable = true;

                app.InfoButton.Enable = false;
                app.OrderEditField.Enable = false;
                app.HighEditField.Enable = false;
                app.LowEditField.Enable = false;
                app.FilterDropDown.Enable = false;
                app.ApplySavitzkyGolayCheckBox.Enable = false;
                app.sg_enabled = false;
                app.SgLength.Enable = false;
                app.SgOrder.Enable = false;

                app.MainPlot.Title = title("Moving RMS");
            else
                app.track = false;

                app.RmsOverlap.Enable = false;
                app.RmsWindow.Enable = false;

                app.InfoButton.Enable = true;
                app.OrderEditField.Enable = true;
                app.HighEditField.Enable = true;
                app.LowEditField.Enable = true;
                app.FilterDropDown.Enable = true;
                app.ApplySavitzkyGolayCheckBox.Enable = true;
                app.ApplySavitzkyGolayCheckBoxValueChanged()


                app.MainPlot.Title = title("Rectification with Band-pass");
            end
            app.reCalculateData();
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            if (value == "STD")
                app.outlier = 1;
            elseif (value == "IQR")
                app.outlier = 2;
            elseif (value == "GESD")
                app.outlier = 3;
            elseif (value == "Z-Score")
                app.outlier = 4;
            end 
            app.reCalculateData();
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged2(app, event)
            value = app.FilterDropDown.Value;
            if (value == "None")
                app.filter = 0;
               h = 0;
               w = 0;
            elseif (value == "Chebyshev 1")
                app.filter = 1;
               [b,a] = cheby1(app.filter_order, 3,[app.filter_low, app.filter_high], "bandpass");
               [h,w] = freqz(b,a);
            elseif (value == "Chebyshev 2")
                app.filter = 2;
               [b,a] = cheby2(app.filter_order, 3, [app.filter_low, app.filter_high], "bandpass");
               [h,w] = freqz(b,a);
            elseif (value == "ButtWorth")
                app.filter = 3;
               [b,a] = butter(app.filter_order, [app.filter_low, app.filter_high], "bandpass");
               [h,w] = freqz(b,a);
            elseif (value == "Elliptical")
                app.filter = 4;
               [b,a] = ellip(app.filter_order, 3, 40, [app.filter_low, app.filter_high], "bandpass");
               [h,w] = freqz(b,a);
            end 
            plot(app.FilterPlot, w/pi,20*log10(abs(h)))
            plot(app.FilterPlotPhase, w, 360/(2*pi)*angle(h))
            
            app.reCalculateData();
            
        end

        % Value changed function: RmsOverlap
        function RmsOverlapValueChanged(app, event)
            value = app.RmsOverlap.Value;
            app.rms_overlap = round(value);
            app.RmsOverlap.Value = app.rms_overlap;
            app.reCalculateData();
            
        end

        % Value changed function: SgLength
        function SgLengthValueChanged(app, event)
            value = app.SgLength.Value;

            app.sg_length = (2*floor(value/2)+1);
            app.SgLength.Value = app.sg_length;
            disp(app.sg_length)
            app.SgOrder.Limits = [1, app.sg_length-2];
            app.reCalculateData();
        end

        % Value changed function: SgOrder
        function SgOrderValueChanged(app, event)
            value = app.SgOrder.Value;
            app.sg_order = round(value);
            app.SgOrder.Value = app.sg_order;
            app.reCalculateData();
            
        end

        % Value changed function: ApplySavitzkyGolayCheckBox
        function ApplySavitzkyGolayCheckBoxValueChanged(app, event)
            value = app.ApplySavitzkyGolayCheckBox.Value;
            if (value)
                app.sg_enabled = true;
                app.SgLength.Enable = true;
                app.SgOrder.Enable = true;
            else
                app.sg_enabled = false;
                app.SgLength.Enable = false;
                app.SgOrder.Enable = false;
            end
            app.reCalculateData();
        end

        % Value changed function: HighEditField
        function HighEditFieldValueChanged(app, event)
            app.HighEditField.Limits = [0, app.sRate/2];
            value = app.HighEditField.Value;
            app.filter_high = value/(app.sRate/2);
            app.reCalculateData();
        end

        % Value changed function: LowEditField
        function LowEditFieldValueChanged(app, event)
            app.LowEditField.Limits = [0, app.sRate/2];
            value = app.LowEditField.Value;
            app.filter_low = value/(app.sRate/2);
            app.reCalculateData();
        end

        % Value changed function: OrderEditField
        function OrderEditFieldValueChanged(app, event)
            value = app.OrderEditField.Value;
            app.filter_order = round(value);
            app.OrderEditField.Value = app.filter_order;
            app.reCalculateData();
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            value = app.FilterDropDown.Value;
            if (value == "None")
                uiconfirm(app.UIFigure,'It does nothing at all... Just like you :)','None filter: explained','Icon','info','Options',{'OK'});

            elseif (value == "Chebyshev 1")
                uiconfirm(app.UIFigure,'This type of filter is the basic type of Chebyshev filter. The amplitude or the gain response is an angular frequency function of the nth order of the LPF (low pass filter) is equal to the total value of the transfer function Hn (jw).','Chebyshev 1: explained',...
                                    'Icon','info','Options',{'OK'});
                
            elseif (value == "Chebyshev 2")
                uiconfirm(app.UIFigure,'The type II Chebyshev filter is also known as an inverse filter, this type of filter is less common. Because, it doesn’t roll off and needs various components. It has no ripple in the passband, but it has equiripple in the stopband.','Chebyshev 2: explained',...
                                    'Icon','info','Options',{'OK'});
                
            elseif (value == "ButtWorth")
                uiconfirm(app.UIFigure,"Butterworth had a reputation for solving impossible mathematical problems. At the time, filter design required a considerable amount of designer experience due to limitations of the theory then in use. The filter was not in common use for over 30 years after its publication. Butterworth stated that: An ideal electrical filter should not only completely reject the unwanted frequencies but should also have uniform sensitivity for the wanted frequencies. Such an ideal filter cannot be achieved, but Butterworth showed that successively closer approximations were obtained with increasing numbers of filter elements of the right values. At the time, filters generated substantial ripple in the passband, and the choice of component values was highly interactive.",'ButtWorth: explained',...
                                    'Icon','info','Options',{'OK'});
                
            elseif (value == "Elliptical")
                uiconfirm(app.UIFigure,"An elliptic filter (also known as a Cauer filter, named after Wilhelm Cauer, or as a Zolotarev filter, after Yegor Zolotarev) is a signal processing filter with equalized ripple (equiripple) behavior in both the passband and the stopband. The amount of ripple in each band is independently adjustable, and no other filter of equal order can have a faster transition in gain between the passband and the stopband, for the given values of ripple (whether the ripple is equalized or not).[citation needed] Alternatively, one may give up the ability to adjust independently the passband and stopband ripple, and instead design a filter which is maximally insensitive to component variations. ",'Elliptical: explained',...
                                    'Icon','info','Options',{'OK'});
               
            end 
        end

        % Button pushed function: OutlierInfo
        function OutlierInfoButtonPushed(app, event)
            value = app.MethodDropDown.Value;
            if (value == "STD")
                uiconfirm(app.UIFigure,'In statistics, the standard deviation is a measure of the amount of variation or dispersion of a set of values. A low standard deviation indicates that the values tend to be close to the mean (also called the expected value) of the set, while a high standard deviation indicates that the values are spread out over a wider range. ','STD: explained','Icon','info','Options',{'OK'});
            elseif (value == "IQR")
                uiconfirm(app.UIFigure,'In descriptive statistics, the interquartile range (IQR) is a measure of statistical dispersion, which is the spread of the data. The IQR may also be called the midspread, middle 50%, or H‑spread. It is defined as the difference between the 75th and 25th percentiles of the data. To calculate the IQR, the data set is divided into quartiles, or four rank-ordered even parts via linear interpolation. These quartiles are denoted by Q1 (also called the lower quartile), Q2 (the median), and Q3 (also called the upper quartile). The lower quartile corresponds with the 25th percentile and the upper quartile corresponds with the 75th percentile, so IQR = Q3 −  Q1.','IQR: explained','Icon','info','Options',{'OK'});
            elseif (value == "GESD")
                uiconfirm(app.UIFigure,'The generalized (extreme Studentized deviate) ESD test (Rosner 1983) is used to detect one or more outliers in a univariate data set that follows an approximately normal distribution. The primary limitation of the Grubbs test and the Tietjen-Moore test is that the suspected number of outliers, k, must be specified exactly. If k is not specified correctly, this can distort the conclusions of these tests. On the other hand, the generalized ESD test (Rosner 1983) only requires that an upper bound for the suspected number of outliers be specified. ','GESD: explained','Icon','info','Options',{'OK'});
            elseif (value == "Z-Score")
                uiconfirm(app.UIFigure,'Z-score is just the number of standard deviations away from the mean that a certain data point is. In your future data science life, Z-scores are gonna be a really useful way to think about how usual or how unusual a certain data point is. And that’s going to be really valuable once we start making inferences based on our data.','Z-Score: explained','Icon','info','Options',{'OK'});
            end   
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            uiconfirm(app.UIFigure,"<b>This amazing piece of software has been made by the function fantastic Alexander Michielsen and the GUI grandmaster Louis de Looze.</b><hr>This program is supplied as is, it is up to the user of the program to decide if the program is suitable for the intended purpose and free from errors.<hr>And remember, <i>The dinosaurs became extinct because they didn't have a space program.</i>"','Fast sEMG','Icon','info','Options',{'Thanks!'},'Interpreter','html');
        end

        % Button pushed function: InfoButton_2
        function InfoButton_2Pushed(app, event)
            uiconfirm(app.UIFigure,'Actually... This is an option for the user that does not work. It is an illusion.','Automatic frequency analysis: explained','Icon','info','Options',{'Sad'});
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {731, 731, 731};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {731, 731};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {225, '1x', 262};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataManagementLabel = uilabel(app.LeftPanel);
            app.DataManagementLabel.Text = 'Data Management';
            app.LoaddataButton = uibutton(app.LeftPanel, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.ExportdataButton = uibutton(app.LeftPanel, 'push');
            app.ExportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdataButtonPushed, true);
            app.ExportdataButton.Text = 'Export data';
            app.MethodDropDownLabel = uilabel(app.LeftPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.LeftPanel);
            app.MethodDropDown.Items = {'STD', 'IQR', 'GESD', 'Z-Score'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'STD';
            app.Image_2 = uiimage(app.LeftPanel);
            app.Image_2.ImageSource = 'logo.png';
            app.lampCH1 = uilamp(app.LeftPanel);
            app.lampCH2 = uilamp(app.LeftPanel);
            app.lampCH3 = uilamp(app.LeftPanel);
            app.lampCH4 = uilamp(app.LeftPanel);
            app.lampCH5 = uilamp(app.LeftPanel);
            app.Tree2 = uitree(app.LeftPanel);
            app.Tree2.SelectionChangedFcn = createCallbackFcn(app, @Tree2SelectionChanged, true);
            app.MTrapDescendensLNode_2 = uitreenode(app.Tree2);
            app.MTrapDescendensLNode_2.Text = 'M. Trap. Descendens (L)';
            app.MTrapDescendensRNode_2 = uitreenode(app.Tree2);
            app.MTrapDescendensRNode_2.Text = 'M. Trap. Descendens (R)';
            app.MDeltodeusAnteriorLNode_2 = uitreenode(app.Tree2);
            app.MDeltodeusAnteriorLNode_2.Text = 'M. Deltoïdeus Anterior (L)';
            app.MDeltodeusAnteriorRNode_2 = uitreenode(app.Tree2);
            app.MDeltodeusAnteriorRNode_2.Text = 'M. Deltoïdeus Anterior (R)';
            app.MExtCarpiRadialisdomNode_2 = uitreenode(app.Tree2);
            app.MExtCarpiRadialisdomNode_2.Text = 'M. Ext. Carpi Radialis (dom.)';
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.BandpassfilterrectificationButton = uiradiobutton(app.ButtonGroup);
            app.BandpassfilterrectificationButton.Text = 'Band pass filter + rectification';
            app.BandpassfilterrectificationButton.Value = true;
            app.MovingRMSButton = uiradiobutton(app.ButtonGroup);
            app.MovingRMSButton.Text = 'Moving RMS';
            app.ProcessingTypeLabel = uilabel(app.LeftPanel);
            app.ProcessingTypeLabel.Text = 'Processing Type';
            app.FrequencyAnalysisLabel = uilabel(app.LeftPanel);
            app.FrequencyAnalysisLabel.Text = 'Frequency Analysis';
            app.Switch_2 = uiswitch(app.LeftPanel, 'slider');
            app.Switch_2.Value = 'On';
            app.AutomaticfilterparametersLabel = uilabel(app.LeftPanel);
            app.AutomaticfilterparametersLabel.Text = {'Automatic '; 'filter parameters'};
            app.OrderLabel = uilabel(app.LeftPanel);
            app.OrderLabel.Text = 'Order';
            app.OrderSlider = uislider(app.LeftPanel);
            app.OrderSlider.Limits = [1 3];
            app.OrderSlider.MajorTicks = [1 2 3];
            app.OrderSlider.ValueChangedFcn = createCallbackFcn(app, @OrderSliderValueChanged, true);
            app.OrderSlider.MinorTicks = [1 2 3];
            app.OrderSlider.Value = 1;
            app.OutlierRemovalLabel = uilabel(app.LeftPanel);
            app.OutlierRemovalLabel.Text = 'Outlier Removal';
            app.OutlierInfo = uibutton(app.LeftPanel, 'push');
            app.OutlierInfo.ButtonPushedFcn = createCallbackFcn(app, @OutlierInfoButtonPushed, true);
            app.OutlierInfo.Text = 'Info';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.InfoButton_2 = uibutton(app.LeftPanel, 'push');
            app.InfoButton_2.ButtonPushedFcn = createCallbackFcn(app, @InfoButton_2Pushed, true);
            app.InfoButton_2.Text = 'Info';
            app.CenterPanel = uipanel(app.GridLayout);
            app.MainPlot = uiaxes(app.CenterPanel);
            title(app.MainPlot, 'Rectification with Band-pass')
            xlabel(app.MainPlot, 'f (Hz)')
            ylabel(app.MainPlot, '|X(k)|')
            zlabel(app.MainPlot, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.FilterPlot = uiaxes(app.RightPanel);
            zlabel(app.FilterPlot, 'Z')
            app.FilterPlot.XTick = [];
            app.FilterPlot.YTick = [];
            app.FilterPlotPhase = uiaxes(app.RightPanel);
            zlabel(app.FilterPlotPhase, 'Z')
            app.FilterPlotPhase.XTick = [];
            app.FilterPlotPhase.YTick = [];
            app.BandPassparametersLabel = uilabel(app.RightPanel);
            app.BandPassparametersLabel.Text = 'Band Pass parameters';
            app.FilterDropDown = uidropdown(app.RightPanel);
            app.FilterDropDown.Items = {'None', 'Chebyshev 1', 'Chebyshev 2', 'ButtWorth', 'Elliptical'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged2, true);
            app.FilterDropDown.Value = 'Chebyshev 1';
            app.HighEditFieldLabel = uilabel(app.RightPanel);
            app.HighEditFieldLabel.Text = 'High';
            app.HighEditField = uieditfield(app.RightPanel, 'numeric');
            app.HighEditField.Limits = [0 500];
            app.HighEditField.ValueChangedFcn = createCallbackFcn(app, @HighEditFieldValueChanged, true);
            app.HighEditField.Value = 400;
            app.OrderEditFieldLabel = uilabel(app.RightPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.RightPanel, 'numeric');
            app.OrderEditField.Limits = [1 20];
            app.OrderEditField.ValueChangedFcn = createCallbackFcn(app, @OrderEditFieldValueChanged, true);
            app.OrderEditField.Value = 1;
            app.ApplySavitzkyGolayCheckBox = uicheckbox(app.RightPanel);
            app.ApplySavitzkyGolayCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplySavitzkyGolayCheckBoxValueChanged, true);
            app.ApplySavitzkyGolayCheckBox.Text = 'Apply Savitzky-Golay';
            app.InfoButton = uibutton(app.RightPanel, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.RMSparametersLabel = uilabel(app.RightPanel);
            app.RMSparametersLabel.Text = 'RMS parameters';
            app.RmsWindow = uislider(app.RightPanel);
            app.RmsWindow.Limits = [2 10];
            app.RmsWindow.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.RmsWindow.ValueChangedFcn = createCallbackFcn(app, @RmsWindowValueChanged, true);
            app.RmsWindow.MinorTicks = [];
            app.RmsWindow.Enable = 'off';
            app.RmsWindow.Value = 4;
            app.Slider_2Label_2 = uilabel(app.RightPanel);
            app.Slider_2Label_2.Tag = 'WindowLabel';
            app.Slider_2Label_2.Text = 'Window';
            app.Slider_2Label_3 = uilabel(app.RightPanel);
            app.Slider_2Label_3.Text = 'Overlap';
            app.RmsOverlap = uislider(app.RightPanel);
            app.RmsOverlap.Limits = [0 3];
            app.RmsOverlap.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.RmsOverlap.ValueChangedFcn = createCallbackFcn(app, @RmsOverlapValueChanged, true);
            app.RmsOverlap.MinorTicks = [];
            app.RmsOverlap.Enable = 'off';
            app.SgLength = uislider(app.RightPanel);
            app.SgLength.Limits = [5 13];
            app.SgLength.MajorTicks = [1 3 5 7 9 11 13];
            app.SgLength.ValueChangedFcn = createCallbackFcn(app, @SgLengthValueChanged, true);
            app.SgLength.MinorTicks = [];
            app.SgLength.Enable = 'off';
            app.SgLength.Value = 7;
            app.Slider_2Label_4 = uilabel(app.RightPanel);
            app.Slider_2Label_4.Tag = 'WindowLabel';
            app.Slider_2Label_4.Text = 'Length';
            app.Slider_2Label_5 = uilabel(app.RightPanel);
            app.Slider_2Label_5.Text = 'Order';
            app.SgOrder = uislider(app.RightPanel);
            app.SgOrder.Limits = [1 5];
            app.SgOrder.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11];
            app.SgOrder.ValueChangedFcn = createCallbackFcn(app, @SgOrderValueChanged, true);
            app.SgOrder.MinorTicks = [];
            app.SgOrder.Enable = 'off';
            app.SgOrder.Value = 3;
            app.LowEditFieldLabel = uilabel(app.RightPanel);
            app.LowEditFieldLabel.Text = 'Low';
            app.LowEditField = uieditfield(app.RightPanel, 'numeric');
            app.LowEditField.Limits = [0 500];
            app.LowEditField.ValueChangedFcn = createCallbackFcn(app, @LowEditFieldValueChanged, true);
            app.LowEditField.Value = 100;
            app.HzLabel = uilabel(app.RightPanel);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.RightPanel);
            app.HzLabel_2.Text = 'Hz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end